classdef Dialog_2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_6           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Button_2          matlab.ui.control.Button
        EditField_7       matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        Button_5          matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        EditField_6       matlab.ui.control.NumericEditField
        EditField_6Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.NumericEditField
        EditField_5Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        Button            matlab.ui.control.Button
        EditField         matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
%         wrap_number = 0.01;
    end
    
    methods (Access = private)
        
        function updateplot2D_square(app, a)
            add_heds_path;
            heds_requires_version(3);
            heds_types;
            heds_slm_init;
            show_slm_preview(1.0);
            wavelength_nm = app.CallingApp.wavelength_nm;  

            focal_length_mm = app.CallingApp.f;
            grayValue = 128;
            grayValueOffset = -128;
            handle = heds_load_data(uint8(grayValue));
            heds_datahandle_waitfor(handle.id, heds_state.ReadyToRender);
            heds_show_datahandle(handle.id);
            heds_utils_wait_s(2.0);

            if a>1
                a=0;
            end

            k = a/app.CallingApp.wrap_number;

            steering_angle_x_deg1 = (a/2):-app.CallingApp.wrap_number:-(a/2);
            steering_angle_x_deg1 = [steering_angle_x_deg1, repelem(-(a/2),k)];
            steering_angle_x_deg1 = [steering_angle_x_deg1, -(a/2):app.CallingApp.wrap_number:(a/2)];
            steering_angle_x_deg1 = [steering_angle_x_deg1, repelem((a/2),k)];
            
            steering_angle_y_deg1 = repelem((a/2),k);
            steering_angle_y_deg1 = [steering_angle_y_deg1, -(a/2):app.CallingApp.wrap_number:(a/2)];
            steering_angle_y_deg1 = [steering_angle_y_deg1, repelem(-(a/2),k)];
            steering_angle_y_deg1 = [steering_angle_y_deg1, (a/2):-app.CallingApp.wrap_number:-(a/2)];


            for x=1:4*k

                steering_angle_x_deg = steering_angle_x_deg1(x);
                steering_angle_y_deg = steering_angle_y_deg1(x); 
                
                beam_lens_param = heds_utils_beam_lens_from_focal_length_mm(wavelength_nm, focal_length_mm);
                beam_steer_x_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_x_deg);
                beam_steer_y_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_y_deg); 
                handle.beamSteerX = beam_steer_x_param;
                handle.beamSteerY = beam_steer_y_param;
                handle.beamLens = beam_lens_param;
                handle.valueOffset = grayValueOffset/255;
                heds_datahandle_apply(handle, heds_datahandle_applyvalue.BeamManipulation + heds_datahandle_applyvalue.ValueOffset);
                heds_utils_wait_s(0.2);
            end
            heds_show_blankscreen(128);


        end 
        
        function updateplot2D_Rectangle(app, a, b)
           
            add_heds_path;
            heds_requires_version(3);
            heds_types;
            heds_slm_init;
            show_slm_preview(1.0);
            wavelength_nm = app.CallingApp.wavelength_nm;  

            focal_length_mm = app.CallingApp.f;
            grayValue = 128;
            grayValueOffset = -128;
            handle = heds_load_data(uint8(grayValue));
            heds_datahandle_waitfor(handle.id, heds_state.ReadyToRender);
            heds_show_datahandle(handle.id);
            heds_utils_wait_s(2.0);

            if a>1
                a=0;
            end
            if b>1
                b=0;
            end

            k1 = a/app.CallingApp.wrap_number;
            k2 = b/app.CallingApp.wrap_number;

            k1 = uint32(k1);k2 = uint32(k2);

            number = app.CallingApp.wrap_number;

            steering_angle_x_deg1 = (a/2):-number:-(a/2);
            steering_angle_x_deg1 = [steering_angle_x_deg1, repelem(-(a/2),k2)];
            steering_angle_x_deg1 = [steering_angle_x_deg1, -(a/2):number:(a/2)];
            steering_angle_x_deg1 = [steering_angle_x_deg1, repelem((a/2),k2)];
            
            steering_angle_y_deg1 = repelem((b/2),k1);
            steering_angle_y_deg1 = [steering_angle_y_deg1, -(b/2):number:(b/2)];
            steering_angle_y_deg1 = [steering_angle_y_deg1, repelem(-(b/2),k1)];
            steering_angle_y_deg1 = [steering_angle_y_deg1, (b/2):-number:-(b/2)];


            for x=1:2*(k1+k2)

                steering_angle_x_deg = steering_angle_x_deg1(x);
                steering_angle_y_deg = steering_angle_y_deg1(x); 
                
                beam_lens_param = heds_utils_beam_lens_from_focal_length_mm(wavelength_nm, focal_length_mm);
                beam_steer_x_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_x_deg);
                beam_steer_y_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_y_deg); 
                handle.beamSteerX = beam_steer_x_param;
                handle.beamSteerY = beam_steer_y_param;
                handle.beamLens = beam_lens_param;
                handle.valueOffset = grayValueOffset/255;
                heds_datahandle_apply(handle, heds_datahandle_applyvalue.BeamManipulation + heds_datahandle_applyvalue.ValueOffset);
                heds_utils_wait_s(0.2);
            end
            heds_show_blankscreen(128);
        end

        function updateplot2D_triangle(app, a, b)

            add_heds_path;
            heds_requires_version(3);
            heds_types;
            heds_slm_init;
            show_slm_preview(1.0);
            wavelength_nm = app.CallingApp.wavelength_nm;  

            focal_length_mm = app.CallingApp.f;
            grayValue = 128;
            grayValueOffset = -128;
            handle = heds_load_data(uint8(grayValue));
            heds_datahandle_waitfor(handle.id, heds_state.ReadyToRender);
            heds_show_datahandle(handle.id);
            heds_utils_wait_s(2.0);

            if a>1
                a=0;
            end
            if b>1
                b=0;
            end

            k1 = a/app.CallingApp.wrap_number;
            k2 = b/app.CallingApp.wrap_number;

%             k1 = uint32(k1);k2 = uint16(k2);

            number = app.CallingApp.wrap_number;
            

            steering_angle_x_deg1 = (a/2):-number:-(a/2);
            steering_angle_x_deg1 = [steering_angle_x_deg1, repelem(-(a/2),k2)];
            steering_angle_x_deg1 = [steering_angle_x_deg1, -(a/2):number:(a/2)];
%             steering_angle_x_deg1 = [steering_angle_x_deg1, repelem((a/2),k2-1)];
            
            steering_angle_y_deg1 = repelem((b/2),k1);
            steering_angle_y_deg1 = [steering_angle_y_deg1, -(b/2):number:(b/2)];
%             steering_angle_y_deg1 = [steering_angle_y_deg1, repelem(-(b/2),k1-1)];
            steering_angle_y_deg1 = [steering_angle_y_deg1, -(b/2):(b/k1):(b/2)];


            for x=1:(k1+k2+k1)

                steering_angle_x_deg = steering_angle_x_deg1(x);
                steering_angle_y_deg = steering_angle_y_deg1(x); 
                
                beam_lens_param = heds_utils_beam_lens_from_focal_length_mm(wavelength_nm, focal_length_mm);
                beam_steer_x_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_x_deg);
                beam_steer_y_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_y_deg); 
                handle.beamSteerX = beam_steer_x_param;
                handle.beamSteerY = beam_steer_y_param;
                handle.beamLens = beam_lens_param;
                handle.valueOffset = grayValueOffset/255;
                heds_datahandle_apply(handle, heds_datahandle_applyvalue.BeamManipulation + heds_datahandle_applyvalue.ValueOffset);
                heds_utils_wait_s(0.2);
            end
            heds_show_blankscreen(128);
        end 

        function updateplot2D_Round(app, a)
            add_heds_path;
            heds_requires_version(3);
            heds_types;
            heds_slm_init;
            show_slm_preview(1.0);
            wavelength_nm = app.CallingApp.wavelength_nm;  

            focal_length_mm = app.CallingApp.f;
            grayValue = 128;
            grayValueOffset = -128;
            handle = heds_load_data(uint8(grayValue));
            heds_datahandle_waitfor(handle.id, heds_state.ReadyToRender);
            heds_show_datahandle(handle.id);
            heds_utils_wait_s(2.0);


            for t=0:app.CallingApp.wrap_number:2.01*pi

                steering_angle_x_deg = 0 + a*cos(t);
                steering_angle_y_deg = 0 + a*sin(t);
                
                beam_lens_param = heds_utils_beam_lens_from_focal_length_mm(wavelength_nm, focal_length_mm);
                beam_steer_x_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_x_deg);
                beam_steer_y_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_y_deg); 
                handle.beamSteerX = beam_steer_x_param;
                handle.beamSteerY = beam_steer_y_param;
                handle.beamLens = beam_lens_param;
                handle.valueOffset = grayValueOffset/255;
                heds_datahandle_apply(handle, heds_datahandle_applyvalue.BeamManipulation + heds_datahandle_applyvalue.ValueOffset);
                heds_utils_wait_s(0.2);
            end
            heds_show_blankscreen(128);

        end         


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp1)
            app.CallingApp = mainapp1;
            app.EditField_7.Value = app.CallingApp.wrap_number;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            updateplot2D_square(app,app.EditField.Value);
%             delete(app)  
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            updateplot2D_Rectangle(app,app.EditField_2.Value,app.EditField_3.Value);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            updateplot2D_triangle(app,app.EditField_4.Value,app.EditField_5.Value);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            updateplot2D_Round(app,app.EditField_6.Value);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.CallingApp.wrap_number = app.EditField_7.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.CallingApp.Button2D.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '边长';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '设置';
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = {'宽'; ''};
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '长';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '长边';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '短边';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '半径';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '设置';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '设置';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '设置';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '间隔';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '设置';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '正方形';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '长方形';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '三角形';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '圆形';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '光斑密度';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dialog_2D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end