classdef Dialog_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button_2              matlab.ui.control.Button
        diaEditField_2        matlab.ui.control.NumericEditField
        umEditFieldLabel_2    matlab.ui.control.Label
        depthEditField_2      matlab.ui.control.NumericEditField
        umEditField_2Label_2  matlab.ui.control.Label
        stepEditField_2       matlab.ui.control.NumericEditField
        mmEditFieldLabel_2    matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        stepEditField         matlab.ui.control.NumericEditField
        mmEditFieldLabel      matlab.ui.control.Label
        depthEditField        matlab.ui.control.NumericEditField
        umEditField_2Label    matlab.ui.control.Label
        Button                matlab.ui.control.Button
        diaEditField          matlab.ui.control.NumericEditField
        umEditFieldLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp   % Main app object
        step_distance = 40
        depth = 200
        dia = 1 

        step_distance1 = 40
        depth1 = 200
        dia1 = 1
    end 
    methods (Access = private)
        
        function updateplot3D(app, a, b, c)
           
            add_heds_path;
            heds_requires_version(3);
            heds_types;
            heds_slm_init;
            show_slm_preview(1.0);
            wavelength_nm = app.CallingApp.wavelength_nm;  

            focal_length_mm = app.CallingApp.f;
            grayValue = 128;
            grayValueOffset = -128;
            handle = heds_load_data(uint8(grayValue));
            heds_datahandle_waitfor(handle.id, heds_state.ReadyToRender);
            heds_show_datahandle(handle.id);
            heds_utils_wait_s(2.0);
            
            for p=1:c:b
                for t=0:app.CallingApp.wrap_number:2.01*pi
    
                    steering_angle_x_deg = 0 + a*cos(t);
                    steering_angle_y_deg = 0 + a*sin(t);
                    
                    beam_lens_param = heds_utils_beam_lens_from_focal_length_mm(wavelength_nm, focal_length_mm);
                    beam_steer_x_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_x_deg);
                    beam_steer_y_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_y_deg); 
                    handle.beamSteerX = beam_steer_x_param;
                    handle.beamSteerY = beam_steer_y_param;
                    handle.beamLens = beam_lens_param;
                    handle.valueOffset = grayValueOffset/255;
                    heds_datahandle_apply(handle, heds_datahandle_applyvalue.BeamManipulation + heds_datahandle_applyvalue.ValueOffset);
                    heds_utils_wait_s(0.2);
                end
            end
            heds_show_blankscreen(128);
        end
        
        function updateplot3D1(app, a1, b1, c1)
            add_heds_path;
            heds_requires_version(3);
            heds_types;
            heds_slm_init;
            show_slm_preview(1.0);
            wavelength_nm = app.CallingApp.wavelength_nm;  

            focal_length_mm = app.CallingApp.f;
            grayValue = 128;
            grayValueOffset = -128;
            handle = heds_load_data(uint8(grayValue));
            heds_datahandle_waitfor(handle.id, heds_state.ReadyToRender);
            heds_show_datahandle(handle.id);
            heds_utils_wait_s(2.0);
            
            for p=1:c1:b1
                for t=0:app.CallingApp.wrap_number:1.01*pi
    
                    steering_angle_x_deg = 0 + a1*cos(t);
                    steering_angle_y_deg = 0 + a1*sin(t);

                    for steering_angle_x_deg=steering_angle_x_deg:app.CallingApp.wrap_number:-steering_angle_x_deg
                        
                        beam_lens_param = heds_utils_beam_lens_from_focal_length_mm(wavelength_nm, focal_length_mm);
                        beam_steer_x_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_x_deg);
                        beam_steer_y_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_y_deg); 
                        handle.beamSteerX = beam_steer_x_param;
                        handle.beamSteerY = beam_steer_y_param;
                        handle.beamLens = beam_lens_param;
                        handle.valueOffset = grayValueOffset/255;
                        heds_datahandle_apply(handle, heds_datahandle_applyvalue.BeamManipulation + heds_datahandle_applyvalue.ValueOffset);
                        heds_utils_wait_s(0.2);
                    
                    end
                    
                end
            end
            heds_show_blankscreen(128);

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp2)
            app.CallingApp = mainapp2;
            app.diaEditField.Value = app.dia;
            app.stepEditField.Value = app.step_distance;
            app.depthEditField.Value = app.depth;

            app.diaEditField_2.Value = app.dia1;
            app.stepEditField_2.Value = app.step_distance1;
            app.depthEditField_2.Value = app.depth1;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            updateplot3D(app,app.diaEditField.Value,app.depthEditField.Value,app.stepEditField.Value);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            updateplot3D(app,app.diaEditField_2.Value,app.depthEditField_2.Value,app.stepEditField_2.Value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Button3D.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.umEditFieldLabel = uilabel(app.UIFigure);
            app.umEditFieldLabel.Text = '直径/um';
            app.diaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '设置';
            app.umEditField_2Label = uilabel(app.UIFigure);
            app.umEditField_2Label.Text = '深度/um';
            app.depthEditField = uieditfield(app.UIFigure, 'numeric');
            app.mmEditFieldLabel = uilabel(app.UIFigure);
            app.mmEditFieldLabel.Text = '轴向步进/mm';
            app.stepEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '螺旋钻孔';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '填充钻孔';
            app.mmEditFieldLabel_2 = uilabel(app.UIFigure);
            app.mmEditFieldLabel_2.Text = '轴向步进/mm';
            app.stepEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.umEditField_2Label_2 = uilabel(app.UIFigure);
            app.umEditField_2Label_2.Text = '深度/um';
            app.depthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.umEditFieldLabel_2 = uilabel(app.UIFigure);
            app.umEditFieldLabel_2.Text = '直径/um';
            app.diaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '设置';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dialog_3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end