classdef Dialog_focus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        nmEditField       matlab.ui.control.NumericEditField
        nmEditFieldLabel  matlab.ui.control.Label
        Button            matlab.ui.control.Button
        mmEditField       matlab.ui.control.NumericEditField
        mmEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp1, f, wavelength_nm)
            app.CallingApp = mainapp1;
            app.mmEditField.Value = f;
            app.nmEditField.Value = wavelength_nm;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            add_heds_path;
            heds_requires_version(3);
            heds_types;
            heds_slm_init;
            show_slm_preview(1.0);
            grayValue = 128;
            grayValueOffset = -128;
            
            wavelength_nm = app.nmEditField.Value; 
            
            steering_angle_x_deg = 0.0;
            steering_angle_y_deg = 0.0;
            
            focal_length_mm = app.mmEditField.Value;
            
            handle = heds_load_data(uint8(grayValue));
            heds_datahandle_waitfor(handle.id, heds_state.ReadyToRender);
            heds_show_datahandle(handle.id);
            heds_utils_wait_s(2.0);
            
            beam_lens_param = heds_utils_beam_lens_from_focal_length_mm(wavelength_nm, focal_length_mm);
            beam_steer_x_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_x_deg);
            beam_steer_y_param = heds_utils_beam_steer_from_angle_deg(wavelength_nm, steering_angle_y_deg);
            
            handle.beamSteerX = beam_steer_x_param;
            handle.beamSteerY = beam_steer_y_param;
            handle.beamLens = beam_lens_param;
            
            handle.valueOffset = grayValueOffset/255;
            heds_datahandle_apply(handle, heds_datahandle_applyvalue.BeamManipulation + heds_datahandle_applyvalue.ValueOffset);

            app.CallingApp.wavelength_nm = wavelength_nm;
            app.CallingApp.f = focal_length_mm;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.CallingApp.Button.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.mmEditFieldLabel = uilabel(app.UIFigure);
            app.mmEditFieldLabel.Text = '去零点焦距/mm';
            app.mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '设置';
            app.nmEditFieldLabel = uilabel(app.UIFigure);
            app.nmEditFieldLabel.Text = '波长/nm';
            app.nmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dialog_focus(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end