classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        Button    matlab.ui.control.Button
        Button3D  matlab.ui.control.Button
        Button2D  matlab.ui.control.Button
        UIAxes2   matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Dialog_2D_app % Description
        Dialog_3D_app 
        Dialog_focus_app

        
    end
    
    properties (Access = public)
        wavelength_nm = 800;
        f = 600;
        wrap_number = 0.01;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        t2=0:0.1:2*pi;
        x2=10*sin(t2);
        y2=10*cos(t2);        
        plot(app.UIAxes, x2,y2,'--ro')  


        t=0:0.03:10*pi;
        x=10*sin(t);
        y=10*cos(t);
        z=t;
        plot3(app.UIAxes2, x,y,z,'--ro')
        end

        % Button pushed function: Button2D
        function Button2DPushed(app, event)
            app.Button2D.Enable = 'off';
            app.Dialog_2D_app = Dialog_2D(app);
        end

        % Button pushed function: Button3D
        function Button3DPushed(app, event)
            app.Button3D.Enable = 'off';
            app.Dialog_3D_app = Dialog_3D(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Enable = 'off';
            app.Dialog_focus_app = Dialog_focus(app, app.f, app.wavelength_nm);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app.Dialog_3D_app)
            delete(app.Dialog_2D_app)
            delete(app.Dialog_focus_app)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2D')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '3D')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button2D = uibutton(app.UIFigure, 'push');
            app.Button2D.ButtonPushedFcn = createCallbackFcn(app, @Button2DPushed, true);
            app.Button2D.Text = '2D轨迹';
            app.Button3D = uibutton(app.UIFigure, 'push');
            app.Button3D.ButtonPushedFcn = createCallbackFcn(app, @Button3DPushed, true);
            app.Button3D.Text = '3D打孔';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '系统设置';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '超快激光微纳制造实验室——光场调控软件';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end